classdef ViewBaseLineStats < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        LastExamDate          matlab.ui.control.Label
        DiagnosticDate        matlab.ui.control.Label
        Walk                  matlab.ui.control.Label
        Day                   matlab.ui.control.Label
        Count                 matlab.ui.control.Label
        Words                 matlab.ui.control.Label
        Name                  matlab.ui.control.Label
        TextArea              matlab.ui.control.TextArea
        DropDown              matlab.ui.control.DropDown
        ShowingStatsforLabel  matlab.ui.control.Label
        UITable               matlab.ui.control.Table
    end

    
    properties (Access = public)
        infoListp1 = [0, 5, 15, 0, 13]; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app1, playerName)
            n = 1;
            app.Name.Text = string(app.infoListp1(n)); % NA
            n= n+1;
            app.Words.Text = "N/A"; % make this part modular
            n=n+1;
            app.Count.Text = string(app.infoListp1(n));
            n=n+1;
            app.Day.Text = "N/A"; %NA
            n=n+1;
            app.Walk.Text = string(app.infoListp1(n));

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Activity'; 'Baseline'; 'Diagnostic Date'; 'Last Exam'};
            app.UITable.RowName = {};
            app.ShowingStatsforLabel = uilabel(app.UIFigure);
            app.ShowingStatsforLabel.Text = 'Showing Stats for:';
            app.DropDown = uidropdown(app.UIFigure);
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Name'; ''; 'Words Remembered'; ''; 'Time for counting'; ''; 'Day/month'; ''; 'Walk'; ''};
            app.Name = uilabel(app.UIFigure);
            app.Name.Text = 'N/A';
            app.Words = uilabel(app.UIFigure);
            app.Words.Text = 'Number';
            app.Count = uilabel(app.UIFigure);
            app.Count.Text = 'Long Day';
            app.Day = uilabel(app.UIFigure);
            app.Day.Text = 'N/A';
            app.Walk = uilabel(app.UIFigure);
            app.Walk.Text = 'WalkTime';
            app.DiagnosticDate = uilabel(app.UIFigure);
            app.DiagnosticDate.Text = '05/27/2005';
            app.LastExamDate = uilabel(app.UIFigure);
            app.LastExamDate.Text = '05/15/24';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ViewBaseLineStats(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end